classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        PlikMenu                        matlab.ui.container.Menu
        WczytajMenu                     matlab.ui.container.Menu
        ZapiszprzetworzonyobrazMenu     matlab.ui.container.Menu
        Menu                            matlab.ui.container.Menu
        rgb2grayMenu                    matlab.ui.container.Menu
        PowrtdooryginauMenu             matlab.ui.container.Menu
        zatwierdButton_5                matlab.ui.control.Button
        zatwierdButton_4                matlab.ui.control.Button
        zatwierdButton_3                matlab.ui.control.Button
        BlueSlider                      matlab.ui.control.Slider
        BlueSliderLabel                 matlab.ui.control.Label
        GreenSlider                     matlab.ui.control.Slider
        GreenSliderLabel                matlab.ui.control.Label
        RedSlider                       matlab.ui.control.Slider
        RedSliderLabel                  matlab.ui.control.Label
        zatwierdButton_2                matlab.ui.control.Button
        NasycenieSlider                 matlab.ui.control.Slider
        NasycenieSliderLabel            matlab.ui.control.Label
        obrazuprzetworzonegoSlider      matlab.ui.control.Slider
        obrazuprzetworzonegoSliderLabel  matlab.ui.control.Label
        zatwierdButton                  matlab.ui.control.Button
        KontrastSlider                  matlab.ui.control.Slider
        KontrastSliderLabel             matlab.ui.control.Label
        PrzytnijButton                  matlab.ui.control.Button
        OdciciekocawideoSlider          matlab.ui.control.Slider
        OdciciekocawideoSliderLabel     matlab.ui.control.Label
        OdciciepocztkuwideoSlider       matlab.ui.control.Slider
        OdciciepocztkuwideoSliderLabel  matlab.ui.control.Label
        OdtwarzajButton_2               matlab.ui.control.Button
        OdtwarzajButton                 matlab.ui.control.Button
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        function result = addToColor(app,value,a)
            result = min(max(value+a,0),255);
            return;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Kontrast(app)
            
        end

        % Menu selected function: WczytajMenu
        function WczytajMenuSelected(app, event)
            global wideo originFrames wideoFrames wideoCount kopiaFrames kopiaCount n m prezentowany tymczasowy
            [name,path] = uigetfile("*.avi", "Wczytaj film");
            
            wideo = VideoReader([path,name]);
            wideo.CurrentTime = 0.0;
            wideoFrames = (read(wideo));
            originFrames = wideoFrames;
            kopiaFrames = wideoFrames;
            n = size(wideoFrames,1);
            m = size(wideoFrames,2);
            wideoCount = size(wideoFrames,4);
            kopiaCount = size(kopiaFrames,4);
            prezentowany = originFrames(:,:,:,1);
            tymczasowy = prezentowany;
            
            wideo.CurrentTime = 0.0;
            disp('Rozmiar wczytanego filmu:');
            disp(size(wideoFrames));
            imshow(wideoFrames(:,:,:,1), 'Parent',app.UIAxes);
            imshow(kopiaFrames(:,:,:,1), 'Parent',app.UIAxes_2);
        end

        % Button pushed function: OdtwarzajButton
        function OdtwarzajButtonPushed(app, event)
            global wideo wideoFrames wideoCount
            i=1;
            while (i<=wideoCount)
               imshow(wideoFrames(:,:,:,i), "Parent", app.UIAxes);
               pause(1/wideo.FrameRate);
               i = i+1;
            end
        end

        % Button pushed function: OdtwarzajButton_2
        function OdtwarzajButton_2Pushed(app, event)
            global wideo kopiaFrames kopiaCount
            i=1;
            while (i<=kopiaCount)
                imshow(kopiaFrames(:,:,:,i), "Parent", app.UIAxes_2);
                pause(1/wideo.FrameRate);
                i = i+1;
            end
        end

        % Button pushed function: PrzytnijButton
        function PrzytnijButtonPushed(app, event)
            global   kopiaFrames kopiaCount
            disp("Przycinam film");
            startCut = get(app.OdciciepocztkuwideoSlider, 'value');
            if startCut < 0
                startCut = 0;
            end
            endCut = get(app.OdciciekocawideoSlider, 'value');
            if endCut > 100
                endCut = 100;
            end
            
            if(startCut < endCut)
               startIdx = floor((startCut/100) * size(kopiaFrames, 4)) + 1;
                endIdx = ceil((endCut/100) * size(kopiaFrames, 4));
                kopiaFrames = kopiaFrames(:,:,:,startIdx:endIdx);
               kopiaCount = size(kopiaFrames,4);
               disp("Zakończono przycinanie filmu");
               imshow(kopiaFrames(:,:,:,1), 'Parent',app.UIAxes_2);
            end
            set(app.OdciciekocawideoSlider, 'value',100);
            set(app.OdciciepocztkuwideoSlider, 'value',0);
        end

        % Menu selected function: ZapiszprzetworzonyobrazMenu
        function ZapiszprzetworzonyobrazMenuSelected(app, event)
            global kopiaFrames;
            [name,path] = uiputfile("*.avi", "Zapisz plik");
            v = VideoWriter([path,name]);
            open(v);
            writeVideo(v,kopiaFrames);
            close(v);
        end

        % Button pushed function: zatwierdButton
        function zatwierdButtonPushed(app, event)
            global kopiaFrames kopiaCount n m prezentowany tymczasowy
            disp('Rozpoczynam nakładanie kontrastu na film.');
            a = get(app.KontrastSlider, 'value');
            a = a+1;
            stosunek = get(app.obrazuprzetworzonegoSlider,'value');
            tymcasowy = prezentowany;
            calcPixelKontrast = @(a,value) min(255, max(0, a * (value - 127.5) + 127.5));
            ni = 1;
            while ni<n
                mi = 1;
                while mi<m-2
                    %rozpisanie wcelu zmniejszenia ilości koniecznych
                    %porównań
                    tymcasowy(ni,mi,1,1) = calcPixelKontrast(a, prezentowany(ni,mi,1,1));
                    tymcasowy(ni,mi,2,1) = calcPixelKontrast(a, prezentowany(ni,mi,2,1));
                    tymcasowy(ni,mi,3,1) = calcPixelKontrast(a, prezentowany(ni,mi,3,1));
                    %drugi pixel
                    tymcasowy(ni,mi+1,1,1) = calcPixelKontrast(a, prezentowany(ni,mi,1,1));
                    tymcasowy(ni,mi+1,2,1) = calcPixelKontrast(a, prezentowany(ni,mi,2,1));
                    tymcasowy(ni,mi+1,3,1) = calcPixelKontrast(a, prezentowany(ni,mi,3,1));

                    tymcasowy(ni,mi+2,1,1) = calcPixelKontrast(a, prezentowany(ni,mi,1,1));
                    tymcasowy(ni,mi+2,2,1) = calcPixelKontrast(a, prezentowany(ni,mi,2,1));
                    tymcasowy(ni,mi+2,3,1) = calcPixelKontrast(a, prezentowany(ni,mi,3,1));

                    tymcasowy(ni,mi+3,1,1) = calcPixelKontrast(a, prezentowany(ni,mi,1,1));
                    tymcasowy(ni,mi+3,2,1) = calcPixelKontrast(a, prezentowany(ni,mi,2,1));
                    tymcasowy(ni,mi+3,3,1) = calcPixelKontrast(a, prezentowany(ni,mi,3,1));
                    %kolejny pixel
                    tymcasowy(ni+1,mi,1,1) = calcPixelKontrast(a, prezentowany(ni,mi,1,1));
                    tymcasowy(ni+1,mi,2,1) = calcPixelKontrast(a, prezentowany(ni,mi,2,1));
                    tymcasowy(ni+1,mi,3,1) = calcPixelKontrast(a, prezentowany(ni,mi,3,1));
                    %kolejny pixel
                    tymcasowy(ni+1,mi+1,1,1) = calcPixelKontrast(a, prezentowany(ni,mi,1,1));
                    tymcasowy(ni+1,mi+1,2,1) = calcPixelKontrast(a, prezentowany(ni,mi,2,1));
                    tymcasowy(ni+1,mi+1,3,1) = calcPixelKontrast(a, prezentowany(ni,mi,3,1));

                    tymcasowy(ni+1,mi+2,1,1) = calcPixelKontrast(a, prezentowany(ni,mi,1,1));
                    tymcasowy(ni+1,mi+2,2,1) = calcPixelKontrast(a, prezentowany(ni,mi,2,1));
                    tymcasowy(ni+1,mi+2,3,1) = calcPixelKontrast(a, prezentowany(ni,mi,3,1));

                    tymcasowy(ni+1,mi+3,1,1) = calcPixelKontrast(a, prezentowany(ni,mi,1,1));
                    tymcasowy(ni+1,mi+3,2,1) = calcPixelKontrast(a, prezentowany(ni,mi,2,1));
                    tymcasowy(ni+1,mi+3,3,1) = calcPixelKontrast(a, prezentowany(ni,mi,3,1));
                    mi = mi +4;
                end
                ni = ni +2;
            end
           
            prezentowany = prezentowany*((100-stosunek)/100) + tymcasowy*(stosunek/100);
            clear tymcasowy;
            imshow(prezentowany, 'Parent',app.UIAxes);
            tymczasowy = prezentowany;
            tmpO = kopiaFrames;
            fprintf('Kontrast progres: %d%%\n',0);
            i=1;
            while i<=kopiaCount
                ni = 1;
                while ni<n
                    mi = 1;
                    while mi<m-2
                        %rozpisanie wcelu zmniejszenia ilości koniecznych
                        %porównań
                        tmpO(ni,mi,1,1) = calcPixelKontrast(a, kopiaFrames(ni,mi,1,1));
                        tmpO(ni,mi,2,1) = calcPixelKontrast(a, kopiaFrames(ni,mi,2,1));
                        tmpO(ni,mi,3,1) = calcPixelKontrast(a, kopiaFrames(ni,mi,3,1));
                        %drugi pixel
                        tmpO(ni,mi+1,1,1) = calcPixelKontrast(a, kopiaFrames(ni,mi,1,1));
                        tmpO(ni,mi+1,2,1) = calcPixelKontrast(a, kopiaFrames(ni,mi,2,1));
                        tmpO(ni,mi+1,3,1) = calcPixelKontrast(a, kopiaFrames(ni,mi,3,1));
    
                        tmpO(ni,mi+2,1,1) = calcPixelKontrast(a, kopiaFrames(ni,mi,1,1));
                        tmpO(ni,mi+2,2,1) = calcPixelKontrast(a, kopiaFrames(ni,mi,2,1));
                        tmpO(ni,mi+2,3,1) = calcPixelKontrast(a, kopiaFrames(ni,mi,3,1));
    
                        tmpO(ni,mi+3,1,1) = calcPixelKontrast(a, kopiaFrames(ni,mi,1,1));
                        tmpO(ni,mi+3,2,1) = calcPixelKontrast(a, kopiaFrames(ni,mi,2,1));
                        tmpO(ni,mi+3,3,1) = calcPixelKontrast(a, kopiaFrames(ni,mi,3,1));
                        %kolejny pixel
                        tmpO(ni+1,mi,1,1) = calcPixelKontrast(a, kopiaFrames(ni,mi,1,1));
                        tmpO(ni+1,mi,2,1) = calcPixelKontrast(a, kopiaFrames(ni,mi,2,1));
                        tmpO(ni+1,mi,3,1) = calcPixelKontrast(a, kopiaFrames(ni,mi,3,1));
                        %kolejny pixel
                        tmpO(ni+1,mi+1,1,1) = calcPixelKontrast(a, kopiaFrames(ni,mi,1,1));
                        tmpO(ni+1,mi+1,2,1) = calcPixelKontrast(a, kopiaFrames(ni,mi,2,1));
                        tmpO(ni+1,mi+1,3,1) = calcPixelKontrast(a, kopiaFrames(ni,mi,3,1));
    
                        tmpO(ni+1,mi+2,1,1) = calcPixelKontrast(a, kopiaFrames(ni,mi,1,1));
                        tmpO(ni+1,mi+2,2,1) = calcPixelKontrast(a, kopiaFrames(ni,mi,2,1));
                        tmpO(ni+1,mi+2,3,1) = calcPixelKontrast(a, kopiaFrames(ni,mi,3,1));
    
                        tmpO(ni+1,mi+3,1,1) = calcPixelKontrast(a, kopiaFrames(ni,mi,1,1));
                        tmpO(ni+1,mi+3,2,1) = calcPixelKontrast(a, kopiaFrames(ni,mi,2,1));
                        tmpO(ni+1,mi+3,3,1) = calcPixelKontrast(a, kopiaFrames(ni,mi,3,1));
                        mi = mi +4;
                    end
                    ni = ni +2;
                end
                kopiaFrames(:,:,:,i) = kopiaFrames(:,:,:,i)*((100-stosunek)/100) + tmpO(:,:,:,i)*(stosunek/100);
                fprintf('Kontrast progres: %d%% \n',uint8(100*(i/kopiaCount)));
                i = i + 1;
            end
            disp('Zakończono nakładanie kontrastu na film.');
            imshow(kopiaFrames(:,:,:,1), 'Parent',app.UIAxes_2);
            clear tmpO;
            set(app.KontrastSlider, 'value',0);
        end

        % Value changing function: KontrastSlider
        function KontrastSliderValueChanging(app, event)
            global n m prezentowany tymczasowy
            disp("Obliczam podgląd kontrastu.");
            a = event.Value;
            a = a + 1;
            stosunek = get(app.obrazuprzetworzonegoSlider,'value');
            tymczasowy = prezentowany;
            calcPixelKontrast = @(a, value) min(255, max(0, a * (value - 127.5) + 127.5));
            ni = 1;
            while ni<n
                mi = 1;
                while mi<m-2
                    %rozpisanie wcelu zmniejszenia ilości koniecznych
                    %porównań
                    tymczasowy(ni,mi,1,1) = calcPixelKontrast(a, prezentowany(ni,mi,1,1));
                    tymczasowy(ni,mi,2,1) = calcPixelKontrast(a, prezentowany(ni,mi,2,1));
                    tymczasowy(ni,mi,3,1) = calcPixelKontrast(a, prezentowany(ni,mi,3,1));
                    %drugi pixel
                    tymczasowy(ni,mi+1,1,1) = calcPixelKontrast(a, prezentowany(ni,mi,1,1));
                    tymczasowy(ni,mi+1,2,1) = calcPixelKontrast(a, prezentowany(ni,mi,2,1));
                    tymczasowy(ni,mi+1,3,1) = calcPixelKontrast(a, prezentowany(ni,mi,3,1));

                    tymczasowy(ni,mi+2,1,1) = calcPixelKontrast(a, prezentowany(ni,mi,1,1));
                    tymczasowy(ni,mi+2,2,1) = calcPixelKontrast(a, prezentowany(ni,mi,2,1));
                    tymczasowy(ni,mi+2,3,1) = calcPixelKontrast(a, prezentowany(ni,mi,3,1));

                    tymczasowy(ni,mi+3,1,1) = calcPixelKontrast(a, prezentowany(ni,mi,1,1));
                    tymczasowy(ni,mi+3,2,1) = calcPixelKontrast(a, prezentowany(ni,mi,2,1));
                    tymczasowy(ni,mi+3,3,1) = calcPixelKontrast(a, prezentowany(ni,mi,3,1));
                    %kolejny pixel
                    tymczasowy(ni+1,mi,1,1) = calcPixelKontrast(a, prezentowany(ni,mi,1,1));
                    tymczasowy(ni+1,mi,2,1) = calcPixelKontrast(a, prezentowany(ni,mi,2,1));
                    tymczasowy(ni+1,mi,3,1) = calcPixelKontrast(a, prezentowany(ni,mi,3,1));
                    %kolejny pixel
                    tymczasowy(ni+1,mi+1,1,1) = calcPixelKontrast(a, prezentowany(ni,mi,1,1));
                    tymczasowy(ni+1,mi+1,2,1) = calcPixelKontrast(a, prezentowany(ni,mi,2,1));
                    tymczasowy(ni+1,mi+1,3,1) = calcPixelKontrast(a, prezentowany(ni,mi,3,1));

                    tymczasowy(ni+1,mi+2,1,1) = calcPixelKontrast(a, prezentowany(ni,mi,1,1));
                    tymczasowy(ni+1,mi+2,2,1) = calcPixelKontrast(a, prezentowany(ni,mi,2,1));
                    tymczasowy(ni+1,mi+2,3,1) = calcPixelKontrast(a, prezentowany(ni,mi,3,1));

                    tymczasowy(ni+1,mi+3,1,1) = calcPixelKontrast(a, prezentowany(ni,mi,1,1));
                    tymczasowy(ni+1,mi+3,2,1) = calcPixelKontrast(a, prezentowany(ni,mi,2,1));
                    tymczasowy(ni+1,mi+3,3,1) = calcPixelKontrast(a, prezentowany(ni,mi,3,1));
                    mi = mi +4;
                end
                ni = ni +2;
            end
         
            tymczasowy = prezentowany*((100-stosunek)/100) + tymczasowy*(stosunek/100);
            disp("Zakończono obliczanie podgląd kontrastu.");
            imshow(tymczasowy, 'Parent',app.UIAxes);
            
        end

        % Value changing function: obrazuprzetworzonegoSlider
        function obrazuprzetworzonegoSliderValueChanging(app, event)
            global tymczasowy prezentowany
            disp("Obliczam podgląd stosunku nałożonego efektu do obrazu.");
            stosunek = event.Value;
            tmp = prezentowany*((100-stosunek)/100) + tymczasowy*(stosunek/100);
            disp("Zakończono obliczanie podglądu stosunku nałożonego efektu do obrazu.");
            imshow(tmp, 'Parent',app.UIAxes);
            clear tmp;
        end

        % Menu selected function: rgb2grayMenu
        function rgb2grayMenuSelected(app, event)
            global kopiaFrames kopiaCount prezentowane tymczasowe
            disp("Rozpoczynam zamianę filmu na skale szarości.");
            i=1;
            while(i<kopiaCount+1)
                kopiaFrames(:,:,1,i) = rgb2gray(kopiaFrames(:,:,:,i));
                kopiaFrames(:,:,2,i) = kopiaFrames(:,:,1,i);
                kopiaFrames(:,:,3,i) = kopiaFrames(:,:,1,i);
                fprintf('RGB2Gray progres: %d%% \n',uint8(100*i/kopiaCount));
                i=i+1;
            end
            prezentowane = kopiaFrames(:,:,:,1);
            tymczasowe = prezentowane;
            disp("Zakończono zamianę filmu na skalę szarości.");
            imshow(prezentowane, 'Parent', app.UIAxes);
            imshow(kopiaFrames(:,:,:,1), 'Parent',app.UIAxes_2);
        end

        % Value changing function: NasycenieSlider
        function NasycenieSliderValueChanging(app, event)
            global prezentowany tymczasowy n m
            disp('Obliczam podgląd nasycenia.');
            value = event.Value;
            value = value/100;
            stosunek = get(app.obrazuprzetworzonegoSlider,'value');
            tmpO = rgb2hsv(prezentowany);
            ni=1;
            while ni<n+1
                mi=1;
                while mi<m+1
                    tmp = tmpO(ni,mi,2) + value;
                    if(tmp>1)
                        tmp=1;
                    end
                    if(tmp<0)
                        tmp=0;
                    end
                    tmpO(ni,mi,2) = tmp;
                    mi=mi+1;
                end
                ni=ni+1;
            end
            tymczasowy = uint8(double(prezentowany)*((100-stosunek)/100) + double(hsv2rgb(tmpO)*255)*(stosunek/100));
            clear tmpO
            disp('Zakończono obliczanie podglądu nasycenia.');
            imshow(tymczasowy, 'Parent',app.UIAxes);
        end

        % Button pushed function: zatwierdButton_2
        function zatwierdButton_2Pushed(app, event)
            global prezentowany kopiaFrames kopiaCount n m tymczasowy
            disp("Rozpoczynam nakładanie nasycenia na film.");
            value = get(app.NasycenieSlider,'value');
            value = value/100;
            stosunek = get(app.obrazuprzetworzonegoSlider,'value');
            tymcasowy = rgb2hsv(prezentowany);
            ni=1;
            while ni<n+1
                mi=1;
                while mi<m+1
                    tmp =  tymcasowy(ni,mi,2) + value;
                    if(tmp>1)
                        tmp=1;
                    end
                    if(tmp<0)
                        tmp=0;
                    end
                    tymcasowy(ni,mi,2) = tmp;
                    mi=mi+1;
                end
                ni=ni+1;
            end
            prezentowany = uint8(double(prezentowany)*((100-stosunek)/100) + double(hsv2rgb(tymcasowy))*255*(stosunek/100));
            imshow(prezentowany, 'Parent',app.UIAxes);
            tymczasowy = prezentowany;
            clear tymcasowy;
            fprintf('Nasycenie progres: %d%% \n',0);
            i=1;
            while i<=kopiaCount
                tmpO = rgb2hsv(kopiaFrames(:,:,:,i));
                ni=1;
                while ni<=n
                    mi=1;
                    while mi<=m
                        tmp = tmpO(ni,mi,2) + value;
                        if(tmp>1)
                            tmp=1;
                        end
                        if(tmp<0)
                            tmp=0;
                        end
                        tmpO(ni,mi,2) = tmp;
                        mi=mi+1;
                    end
                    ni=ni+1;
                end
                kopiaFrames(:,:,:,i) = uint8(double(kopiaFrames(:,:,:,i)) * ((100-stosunek)/100) + double(hsv2rgb(tmpO))*255*(stosunek/100));
                fprintf('Nasycenie progres: %d%% \n',uint8(100*(i/kopiaCount)));
                i=i+1;
            end
            disp("Zakończono nakładanie nasycenia na film.");
            imshow(kopiaFrames(:,:,:,1), 'Parent',app.UIAxes_2);
            set(app.NasycenieSlider, 'value',0);

        end

        % Value changing function: RedSlider
        function RedSliderValueChanging(app, event)
            global prezentowany n m  tymczasowy
            disp("Obliczanie podglądu zmodyfikowanej składowej czerwonej");
            a = event.Value;
            stosunek = get(app.obrazuprzetworzonegoSlider,'value');
            tymczasowy = prezentowany;
            ni=1;
            while ni<n+1
                mi=1;
                while mi<m+1
                    tymczasowy(ni,mi,1,1) = prezentowany(ni,mi,1,1)*((100-stosunek)/100) + (stosunek/100)*addToColor(app,prezentowany(ni,mi,1,1),a);
                    mi=mi+1;
                end
                ni=ni+1;
            end
            disp("Zakończono obliczanie podglądu zmodyfikowanej składowej czerwonej");
            imshow(tymczasowy, 'Parent',app.UIAxes);
        end

        % Value changing function: GreenSlider
        function GreenSliderValueChanging(app, event)
            global prezentowany n m tymczasowy
            disp("Obliczanie podglądu zmodyfikowanej składowej zielonej.");
            a = event.Value;
            stosunek = get(app.obrazuprzetworzonegoSlider,'value');
            tymczasowy = prezentowany;
            ni=1;
            while ni<n+1
                mi=1;
                while mi<m+1
                    tymczasowy(ni,mi,2,1) = prezentowany(ni,mi,2,1)*((100-stosunek)/100) + (stosunek/100)*addToColor(app,prezentowany(ni,mi,2,1),a);
                    mi=mi+1;
                end
                ni=ni+1;
            end
            disp("Zakończono obliczanie podglądu zmodyfikowanej składowej zielonej.");
            imshow(tymczasowy, 'Parent',app.UIAxes);
        end

        % Value changing function: BlueSlider
        function BlueSliderValueChanging(app, event)
            global prezentowany n m tymczasowy
            disp("Obliczanie podglądu zmodyfikowanej składowej niebieskiej.");
            a = event.Value;
            stosunek = get(app.obrazuprzetworzonegoSlider,'value');
            tymczasowy = prezentowany;
            ni=1;
            while ni<n+1
                mi=1;
                while mi<m+1
                    tymczasowy(ni,mi,3,1) = prezentowany(ni,mi,3,1)*((100-stosunek)/100) + (stosunek/100)*addToColor(app,prezentowany(ni,mi,3,1),a);
                    mi=mi+1;
                end
                ni=ni+1;
            end
            disp("Zakończono obliczanie podglądu zmodyfikowanej składowej niebieskiej.");
            imshow(tymczasowy, 'Parent',app.UIAxes);
        end

        % Button pushed function: zatwierdButton_3
        function zatwierdButton_3Pushed(app, event)
            global prezentowany n m kopiaFrames kopiaCount tymczasowy
            disp("Rozpoczynam modyfikacje składowej czerwonej w filmie.");
            a = get(app.RedSlider,'value');
            stosunek = get(app.obrazuprzetworzonegoSlider,'value');
            ni=1;
            while ni<n+1
                mi=1;
                while mi<m+1
                    prezentowany(ni,mi,1,1) = prezentowany(ni,mi,1,1)*((100-stosunek)/100) + (stosunek/100)*addToColor(app,prezentowany(ni,mi,1,1),a);
                    mi=mi+1;
                end
                ni=ni+1;
            end
            imshow(prezentowany, 'Parent',app.UIAxes);
            tymczasowy = prezentowany;
            fprintf('Red progres: %d%%\n',0);
            i=1;
            while i<kopiaCount+1
                ni=1;
                while ni<n+1
                    mi=1;
                    while mi<m+1
                        kopiaFrames(ni,mi,1,i) = kopiaFrames(ni,mi,1,i)*((100-stosunek)/100) + (stosunek/100)*addToColor(app,kopiaFrames(ni,mi,1,i),a);
                        mi=mi+1;
                    end
                    ni=ni+1;
                end
                fprintf('Red progres: %d%%\n',uint8(100*i/kopiaCount));
                i=i+1;
            end
            imshow(kopiaFrames(:,:,:,1), 'Parent',app.UIAxes_2);
            disp("Zakończono modyfikacje składowej czerwonej w filmie.");
            set(app.RedSlider, 'value',0);
        end

        % Button pushed function: zatwierdButton_4
        function zatwierdButton_4Pushed(app, event)
            global prezentowany n m kopiaFrames kopiaCount tymczasowy
            disp("Rozpoczynam modyfikacje składowej zielonej w filmie.");
            a = get(app.GreenSlider,'value');
            stosunek = get(app.obrazuprzetworzonegoSlider,'value');
            ni=1;
            while ni<n+1
                mi=1;
                while mi<m+1
                    prezentowany(ni,mi,2,1) = prezentowany(ni,mi,2,1)*((100-stosunek)/100) + (stosunek/100)*addToColor(app,prezentowany(ni,mi,2,1),a);
                    mi=mi+1;
                end
                ni=ni+1;
            end
            imshow(prezentowany, 'Parent',app.UIAxes);
            tymczasowy = prezentowany;
            fprintf('Green progres: %d%%\n',0);
            i=1;
            while i<kopiaCount+1
                ni=1;
                while ni<n+1
                    mi=1;
                    while mi<m+1
                        kopiaFrames(ni,mi,2,i) = kopiaFrames(ni,mi,2,i)*((100-stosunek)/100) + (stosunek/100)*addToColor(app,kopiaFrames(ni,mi,2,i),a);
                        mi=mi+1;
                    end
                    ni=ni+1;
                end
                fprintf('Green progres: %d%%\n',uint8(100*i/kopiaCount));
                i=i+1;
            end
            imshow(kopiaFrames(:,:,:,1), 'Parent',app.UIAxes_2);
            disp("Zakończono modyfikacje składowej zielonej w filmie.");
            set(app.GreenSlider, 'value',0);
        end

        % Button pushed function: zatwierdButton_5
        function zatwierdButton_5Pushed(app, event)
            global prezentowany n m kopiaFrames kopiaCount tymczasowy
            disp("Rozpoczynam modyfikacje składowej niebieskiej w filmie.");
            a = get(app.BlueSlider,'value');
            stosunek = get(app.obrazuprzetworzonegoSlider,'value');
            ni=1;
            while ni<n+1
                mi=1;
                while mi<m+1
                    prezentowany(ni,mi,3,1) = prezentowany(ni,mi,3,1)*((100-stosunek)/100) + (stosunek/100)*addToColor(app,prezentowany(ni,mi,3,1),a);
                    mi=mi+1;
                end
                ni=ni+1;
            end
            imshow(prezentowany, 'Parent',app.UIAxes);
            tymczasowy = prezentowany;
            fprintf('Blue progres: %d%%\n',0);
            i=1;
            while i<kopiaCount+1
                ni=1;
                while ni<n+1
                    mi=1;
                    while mi<m+1
                        kopiaFrames(ni,mi,3,i) = kopiaFrames(ni,mi,3,i)*((100-stosunek)/100) + (stosunek/100)*addToColor(app,kopiaFrames(ni,mi,3,i),a);
                        mi=mi+1;
                    end
                    ni=ni+1;
                end
                fprintf('Blue progres: %d%%\n',uint8(100*i/kopiaCount));
                i=i+1;
            end
            imshow(kopiaFrames(:,:,:,1), 'Parent',app.UIAxes_2);
            disp("Zakończono modyfikacje składowej niebieskiej w filmie.");
            set(app.BlueSlider, 'value',0);
        end

        % Menu selected function: PowrtdooryginauMenu
        function PowrtdooryginauMenuSelected(app, event)
            global   wideoFrames wideoCount kopiaFrames kopiaCount prezentowany tymczasowy
            kopiaFrames = wideoFrames;
            kopiaCount = wideoCount;
            prezentowany = wideoFrames(:,:,:,1);
            tymczasowy = prezentowany;
            set(app.OdciciekocawideoSlider, 'value',100);
            set(app.OdciciepocztkuwideoSlider, 'value',0);

            set(app.KontrastSlider, 'value',0);
            set(app.NasycenieSlider, 'value',0);

            set(app.obrazuprzetworzonegoSlider, 'value',100);

            set(app.RedSlider, 'value',0);
            set(app.GreenSlider, 'value',0);
            set(app.BlueSlider, 'value',0);
            imshow(wideoFrames(:,:,:,1), 'Parent',app.UIAxes);
            imshow(kopiaFrames(:,:,:,1), 'Parent',app.UIAxes_2);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PlikMenu = uimenu(app.UIFigure);
            app.PlikMenu.Text = 'Plik  ';
            app.WczytajMenu = uimenu(app.PlikMenu);
            app.WczytajMenu.MenuSelectedFcn = createCallbackFcn(app, @WczytajMenuSelected, true);
            app.WczytajMenu.Text = 'Wczytaj';
            app.ZapiszprzetworzonyobrazMenu = uimenu(app.PlikMenu);
            app.ZapiszprzetworzonyobrazMenu.MenuSelectedFcn = createCallbackFcn(app, @ZapiszprzetworzonyobrazMenuSelected, true);
            app.ZapiszprzetworzonyobrazMenu.Text = 'Zapisz przetworzony obraz';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.rgb2grayMenu = uimenu(app.Menu);
            app.rgb2grayMenu.MenuSelectedFcn = createCallbackFcn(app, @rgb2grayMenuSelected, true);
            app.rgb2grayMenu.Text = 'rgb2gray';
            app.PowrtdooryginauMenu = uimenu(app.Menu);
            app.PowrtdooryginauMenu.MenuSelectedFcn = createCallbackFcn(app, @PowrtdooryginauMenuSelected, true);
            app.PowrtdooryginauMenu.Text = 'Powrót do oryginału';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Obraz przetwarzany')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Obraz przetworzony')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.OdtwarzajButton = uibutton(app.UIFigure, 'push');
            app.OdtwarzajButton.ButtonPushedFcn = createCallbackFcn(app, @OdtwarzajButtonPushed, true);
            app.OdtwarzajButton.Text = {'Odtwarzaj'; ''};
            app.OdtwarzajButton_2 = uibutton(app.UIFigure, 'push');
            app.OdtwarzajButton_2.ButtonPushedFcn = createCallbackFcn(app, @OdtwarzajButton_2Pushed, true);
            app.OdtwarzajButton_2.Text = {'Odtwarzaj'; ''};
            app.OdciciepocztkuwideoSliderLabel = uilabel(app.UIFigure);
            app.OdciciepocztkuwideoSliderLabel.Text = 'Odcięcie początku wideo';
            app.OdciciepocztkuwideoSlider = uislider(app.UIFigure);
            app.OdciciepocztkuwideoSlider.Value = 0.01;
            app.OdciciekocawideoSliderLabel = uilabel(app.UIFigure);
            app.OdciciekocawideoSliderLabel.Text = {'Odcięcie końca wideo'; ''};
            app.OdciciekocawideoSlider = uislider(app.UIFigure);
            app.OdciciekocawideoSlider.Value = 100;
            app.PrzytnijButton = uibutton(app.UIFigure, 'push');
            app.PrzytnijButton.ButtonPushedFcn = createCallbackFcn(app, @PrzytnijButtonPushed, true);
            app.PrzytnijButton.Text = 'Przytnij';
            app.KontrastSliderLabel = uilabel(app.UIFigure);
            app.KontrastSliderLabel.Text = 'Kontrast';
            app.KontrastSlider = uislider(app.UIFigure);
            app.KontrastSlider.Limits = [-2 2];
            app.KontrastSlider.ValueChangingFcn = createCallbackFcn(app, @KontrastSliderValueChanging, true);
            app.zatwierdButton = uibutton(app.UIFigure, 'push');
            app.zatwierdButton.ButtonPushedFcn = createCallbackFcn(app, @zatwierdButtonPushed, true);
            app.zatwierdButton.Text = 'zatwierdź';
            app.obrazuprzetworzonegoSliderLabel = uilabel(app.UIFigure);
            app.obrazuprzetworzonegoSliderLabel.Text = {'% obrazu'; 'przetworzonego'};
            app.obrazuprzetworzonegoSlider = uislider(app.UIFigure);
            app.obrazuprzetworzonegoSlider.MajorTicks = [0 25 50 75 100];
            app.obrazuprzetworzonegoSlider.ValueChangingFcn = createCallbackFcn(app, @obrazuprzetworzonegoSliderValueChanging, true);
            app.obrazuprzetworzonegoSlider.Value = 100;
            app.NasycenieSliderLabel = uilabel(app.UIFigure);
            app.NasycenieSliderLabel.Text = 'Nasycenie';
            app.NasycenieSlider = uislider(app.UIFigure);
            app.NasycenieSlider.Limits = [-100 100];
            app.NasycenieSlider.ValueChangingFcn = createCallbackFcn(app, @NasycenieSliderValueChanging, true);
            app.zatwierdButton_2 = uibutton(app.UIFigure, 'push');
            app.zatwierdButton_2.ButtonPushedFcn = createCallbackFcn(app, @zatwierdButton_2Pushed, true);
            app.zatwierdButton_2.Text = 'zatwierdź';
            app.RedSliderLabel = uilabel(app.UIFigure);
            app.RedSliderLabel.Text = 'Red';
            app.RedSlider = uislider(app.UIFigure);
            app.RedSlider.Limits = [-255 255];
            app.RedSlider.MajorTicks = [-255 0 255];
            app.RedSlider.ValueChangingFcn = createCallbackFcn(app, @RedSliderValueChanging, true);
            app.GreenSliderLabel = uilabel(app.UIFigure);
            app.GreenSliderLabel.Text = 'Green';
            app.GreenSlider = uislider(app.UIFigure);
            app.GreenSlider.Limits = [-255 255];
            app.GreenSlider.MajorTicks = [-255 0 255];
            app.GreenSlider.ValueChangingFcn = createCallbackFcn(app, @GreenSliderValueChanging, true);
            app.BlueSliderLabel = uilabel(app.UIFigure);
            app.BlueSliderLabel.Text = 'Blue';
            app.BlueSlider = uislider(app.UIFigure);
            app.BlueSlider.Limits = [-255 255];
            app.BlueSlider.MajorTicks = [-255 0 255];
            app.BlueSlider.ValueChangingFcn = createCallbackFcn(app, @BlueSliderValueChanging, true);
            app.zatwierdButton_3 = uibutton(app.UIFigure, 'push');
            app.zatwierdButton_3.ButtonPushedFcn = createCallbackFcn(app, @zatwierdButton_3Pushed, true);
            app.zatwierdButton_3.Text = 'zatwierdź';
            app.zatwierdButton_4 = uibutton(app.UIFigure, 'push');
            app.zatwierdButton_4.ButtonPushedFcn = createCallbackFcn(app, @zatwierdButton_4Pushed, true);
            app.zatwierdButton_4.Text = 'zatwierdź';
            app.zatwierdButton_5 = uibutton(app.UIFigure, 'push');
            app.zatwierdButton_5.ButtonPushedFcn = createCallbackFcn(app, @zatwierdButton_5Pushed, true);
            app.zatwierdButton_5.Text = 'zatwierdź';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @Kontrast)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end